classdef Multires_ML_Microscopy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        FitImagestoSizeLabel      matlab.ui.control.Label
        FitImages                 matlab.ui.control.Switch
        Label_19                  matlab.ui.control.Label
        Label_18                  matlab.ui.control.Label
        Label_17                  matlab.ui.control.Label
        Label_16                  matlab.ui.control.Label
        Label_15                  matlab.ui.control.Label
        Label_14                  matlab.ui.control.Label
        SizeMax                   matlab.ui.control.NumericEditField
        ObjectSize                matlab.ui.control.RangeSlider
        Label_13                  matlab.ui.control.Label
        Label_11                  matlab.ui.control.Label
        Label_10                  matlab.ui.control.Label
        Label_9                   matlab.ui.control.Label
        Label_8                   matlab.ui.control.Label
        Label_7                   matlab.ui.control.Label
        Label_6                   matlab.ui.control.Label
        TrackingOption            matlab.ui.control.Switch
        Label_2                   matlab.ui.control.Label
        SizeMin                   matlab.ui.control.NumericEditField
        LevelEditFieldLabel_8     matlab.ui.control.Label
        CloseButton               matlab.ui.control.Button
        DWTThresh_t               matlab.ui.control.NumericEditField
        MinIOUTrack               matlab.ui.control.Slider
        IOUTrackText              matlab.ui.control.NumericEditField
        RPNOptAlphaText           matlab.ui.control.NumericEditField
        Alpha                     matlab.ui.control.Slider
        LevelEditFieldLabel_7     matlab.ui.control.Label
        LevelEditFieldLabel_6     matlab.ui.control.Label
        TrackingLabel_2           matlab.ui.control.Label
        DenoiseSwitch             matlab.ui.control.Switch
        MaxProposals              matlab.ui.control.NumericEditField
        LevelEditFieldLabel_5     matlab.ui.control.Label
        OverlapProp_t             matlab.ui.control.NumericEditField
        OverlaPP_t                matlab.ui.control.NumericEditField
        LevelEditFieldLabel_4     matlab.ui.control.Label
        LevelEditFieldLabel_3     matlab.ui.control.Label
        OverlapProp               matlab.ui.control.Slider
        OverlaPP                  matlab.ui.control.Slider
        DropDown                  matlab.ui.control.DropDown
        SegmentationNetworkLabel  matlab.ui.control.Label
        LevelEditFieldLabel_2     matlab.ui.control.Label
        DWTThresh                 matlab.ui.control.Slider
        DWTLEv                    matlab.ui.control.NumericEditField
        LevelEditFieldLabel       matlab.ui.control.Label
        PreProcessingLabel        matlab.ui.control.Label
        AdvancedLabel             matlab.ui.control.Label
        ConfidenceField           matlab.ui.control.NumericEditField
        Confidence                matlab.ui.control.Slider
        ConfidenceThresholdLabel  matlab.ui.control.Label
        RelaxedButton             matlab.ui.control.Button
        ConservativeButton        matlab.ui.control.Button
        DefaultButton             matlab.ui.control.Button
        PresetLabel               matlab.ui.control.Label
        SettingsLabel             matlab.ui.control.Label
        ExportPanel               matlab.ui.container.Panel
        JSONFileCheckBox          matlab.ui.control.CheckBox
        ExportButton              matlab.ui.control.Button
        matFileCheckBox           matlab.ui.control.CheckBox
        CSVFileCheckBox           matlab.ui.control.CheckBox
        CloseButton_2             matlab.ui.control.Button
        ExportDataLabel           matlab.ui.control.Label
        InitialLabel              matlab.ui.control.Label
        YFeatureLabel_2           matlab.ui.control.Label
        XFeatureLabel_2           matlab.ui.control.Label
        PlotIndex                 matlab.ui.control.NumericEditField
        PauseButton               matlab.ui.control.Button
        PlotFeaturesText          matlab.ui.control.Label
        NextButton                matlab.ui.control.Button
        PreviousButton            matlab.ui.control.Button
        PlotMode                  matlab.ui.control.Switch
        Reset                     matlab.ui.control.Button
        ImageDisp                 matlab.ui.control.Image
        ExportData_Button         matlab.ui.control.Button
        Credit                    matlab.ui.control.Hyperlink
        XDropDown                 matlab.ui.control.DropDown
        YDropDown                 matlab.ui.control.DropDown
        Number_of_Cells_Field     matlab.ui.control.NumericEditField
        Number_of_Cells_Text      matlab.ui.control.Label
        Segment_Button            matlab.ui.control.Button
        Help_Button               matlab.ui.control.Button
        Settings_Button           matlab.ui.control.Button
        UploadImagesButton        matlab.ui.control.Button
        ProgressBarAxes           matlab.ui.control.UIAxes
        HistogramAxes             matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
    Images cell = {}; % Stores the uploaded image
    Filenames cell = {};
    CurrentImageIndex int8 = 1;
    SegmentedImages = []; % Stores the segmented image
    net = []; % Stores the pre-trained CNN network
    Masks cell = {};
    Boxes cell = {};
    Labels cell = {};
    Scores cell = {};
    SingleMasks = [];
    SingleBoxes = [];
    SingleScores = [];
    SingleLabels = [];
    AnalysisData = [];
    Progress = 0; 
    TrackingInfo table = [];
    SingleImage logical = 0; 
    AggregateAnalysis table = [];
    Tracks struct = [];
    end
    
    methods (Access = private)
        
        function [] = IMGDisplay(app, Image, ImageDisp) %function to display image file without labels properly

        if size(Image, 3)==1
            ImageDisp.ImageSource = repmat(Image, [1 1 3]);
        elseif size(Image, 3)==3
            ImageDisp.ImageSource = Image;
        end
       
        end


        function UpdateProgress(app)
            barh(app.Progress, 'Parent',app.ProgressBarAxes,'EdgeColor','none','FaceColor',[0.4660 0.6740 0.1880], "BarWidth" ,23000)
            
            if app.Progress>0
                text(0.43,0, [num2str(app.Progress*100,'%.0f')+"%"] ,'Parent',app.ProgressBarAxes,'FontSize',20,'FontWeight','bold')
            end
            drawnow
        end

        function highlightButton(app, selectedButton)
    allButtons = [app.DefaultButton, app.ConservativeButton, app.RelaxedButton];

    for btn = allButtons
        btn.BackgroundColor = [0.76 0.80 0.80];  
        btn.FontColor = [0 0 0];                
    end

    selectedButton.BackgroundColor = [0.05 0.30 0.30];  % Blue highlight
    selectedButton.FontColor = [1 1 1];                % White text
end

        
        function UpdateIm(app)
            
         i = app.CurrentImageIndex;
         DispColors = 1.4 - 0.5*rescale(app.Scores{i})+0.05*rand([length(app.Scores{i}) 1]);
         DispColors = DispColors .* [0.466 0.674	0.188];
            
        if isempty(app.Masks{i})
            app.SegmentedImages = app.Images{i};
        else
            app.SegmentedImages = insertObjectMask(app.Images{i}, app.Masks{i}, Color=DispColors );
        end
        % Display the segmented image with masks
        IMGDisplay(app, app.SegmentedImages, app.ImageDisp); drawnow;

        app.Number_of_Cells_Field.Value = length(app.Scores{i});


        end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            IMGDisplay(app, ones(520, 740)*0.9, app.ImageDisp);
            %addpath(genpath('Multires_ML_Microscopy'));
            app.net = load('EfficientNet.mat'); % Load the network file 
            app.net = app.net.net; % Extract the network object
            set(app.ProgressBarAxes,'visible','off');
            set(app.ProgressBarAxes,'xtick',[]);
            UpdateProgress(app);
            set(app.ProgressBarAxes, 'XLim', [0 1]);
            DefaultButtonPushed(app);
        end

        % Callback function: ImageDisp, UploadImagesButton
        function UploadImage(app, event)
            app.InitialLabel.Visible = 'off';
            
            [files, path] = uigetfile({'*.png;*.jpg;*.tif'}, 'Select Images', 'MultiSelect', 'on');
           if isequal(files, 0)
           %     errordlg('No images selected!', 'Error');
                return;
           end


            if ~iscell(files)
                files = {files};
            end


            app.Images = {};
            app.Filenames = {};

            for k = 1:length(files)
                img = rescale(imread(fullfile(path, files{k})));
                if size(img, 3) == 3
                    img = mean(img, 3);
                end

                if strcmp(app.FitImages.Value, 'On')
                    img = imresize(img, [528 704],"bilinear");
                else
                    img = smartResize(img, [528 704]);
                end
            
                if ~iscell(img)
                  img = {img};
                end


                for j = 1:length(img)
                    app.Images{end+1} = img{j};
                    app.Filenames{end+1} = files{k};
                end
            end

            app.CurrentImageIndex = 1;
            app.SegmentedImages = {};
            app.Masks = {};
            app.Boxes = {};
            app.Labels = {};
            app.Scores = {};
            app.AnalysisData = {};
            IMGDisplay(app, app.Images{1}, app.ImageDisp);
            
            app.Segment_Button.Visible='on';
        end

        % Button pushed function: Segment_Button
        function Segment(app, event)
        if isempty(app.Images)
        errordlg('Please upload an image first!', 'Error');
        return;
        end 
        
        %app.Segment_Button.Visible='off';
        app.Number_of_Cells_Field.Visible = 'on';
        app.Number_of_Cells_Text.Visible = 'on';
        app.PauseButton.Visible = 'on';
       
        %set up properties and variables
        TrackingData = [];
        tracks = [];
        trackingTable = [];
        minsize = [app.SizeMin.Value app.SizeMin.Value];
        maxsize = [app.SizeMax.Value app.SizeMax.Value];

        app.net.OverlapThresholdPrediction = app.OverlaPP.Value / 100;
        app.net.OverlapThresholdRPN = app.OverlapProp.Value / 100;

        app.net.NumStrongestRegions = app.MaxProposals.Value;
        app.net.NumStrongestRegionsPrediction = app.MaxProposals.Value;


        if    strcmp(app.DenoiseSwitch.Value,'On')
            denoiseOF = 1;
        else
            denoiseOF = 0;
        end


    uiresume(app.UIFigure);

    % Perform cell segmentation
    try
        if ~class(app.Images)=='cell'
            app.Images{1} = app.Images; %makes sure all images have same format: cell, containing each image
            % move this logic to images being loaded later 
        end


        for i=[1:size(app.Images,2)] %for each image loaded in
        
         app.Progress = ((i-1)+ 0.05) / size(app.Images,2);
         UpdateProgress(app);
         if size(app.Images,2)==1
             app.SingleImage = 1;
             [app.SingleMasks, app.SingleLabels, app.SingleScores, app.SingleBoxes] = segmentCells(app.net, rescale(app.Images{i}), "NumstrongestRegions",Inf, MinSize=minsize, MaxSize=maxsize,SelectStrongest=true, SegmentThreshold=1e-7,Denoise=denoiseOF,DWTThreshold=app.DWTThresh.Value/100, Level=ceil(app.DWTLEv.Value) );
             
             
             app.Scores{1} = app.SingleScores(app.SingleScores>app.Confidence.Value/100);
             app.Labels{1} = app.SingleLabels(app.SingleScores>app.Confidence.Value/100);
             app.Masks{1}  = app.SingleMasks(:, :,app.SingleScores>app.Confidence.Value/100);
             app.Boxes{1}  = app.SingleBoxes(app.SingleScores>app.Confidence.Value/100, :);

         elseif strcmp(app.TrackingOption.Value, 'On')
            app.SingleImage = 0;
            [app.Masks{i}, app.Labels{i}, app.Scores{i}, app.Boxes{i}, TrackingData] =  segmentFrame(app.net, rescale(app.Images{i}), TrackingData, "NumStrongestRegions",Inf, Threshold=app.Confidence.Value/100,Alpha=app.Alpha.Value/100,MinSize=minsize, MaxSize=maxsize);
            
            app.Progress = ((i-1)+ 0.7) / size(app.Images,2);
            UpdateProgress(app);

            [tracks, trackingTable] = createTracks(tracks, app.Boxes{i}, app.Scores{i}, i, trackingTable, "MinIoU",app.MinIOUTrack.Value,"MaxInvisibleCount",5,'MaxDistance',250);
         else
            app.SingleImage = 0;
            [app.Masks{i}, app.Labels{i}, app.Scores{i}, app.Boxes{i}] = segmentCells(app.net, rescale(app.Images{i}), "NumstrongestRegions",Inf, MinSize=minsize, MaxSize=maxsize,SelectStrongest=true, SegmentThreshold=app.Confidence.Value/100,Denoise=denoiseOF,DWTThreshold=app.DWTThresh.Value/100, Level=ceil(app.DWTLEv.Value) );
         end 
         app.Progress = ((i-1)+ 0.85) / size(app.Images,2);
         UpdateProgress(app);
       

        app.CurrentImageIndex = i;
        UpdateIm(app);

        
        app.AnalysisData{i} = ExtractFeatures(app.Images{i}, app.Masks{i}, app.Boxes{i}, app.Scores{i}, 1);
       % app.AnalysisData{1}.Area

        % Update the cell count field
        app.Number_of_Cells_Field.Value = size(app.AnalysisData{i}, 1);
        %app.Area_Field.Value = mean(app.AnalysisData.Area);
        
        end

        
        BigTable = [];
for i = 1:size(app.AnalysisData,2)
    Table_i = app.AnalysisData{i};

    ImageID = repmat(i, [size(Table_i, 1), 1]);
    ImageID = table(ImageID);
    FileName = repmat(string(app.Filenames{i}), [size(Table_i, 1), 1]);
    FileName = table(FileName);

    Table_i = [ImageID FileName Table_i];


    BigTable = [BigTable; Table_i];
end

        app.AggregateAnalysis = BigTable;
       
        app.TrackingInfo = trackingTable;
        app.Tracks = tracks;


        app.Progress = i / size(app.Images,2);
        UpdateProgress(app);


        app.PauseButton.Visible = 'off';
        app.ExportData_Button.Visible = 'on';
        app.PlotIndex.Visible = 'on'; 
        app.PlotFeaturesText.Visible = 'on';
        app.PlotMode.Visible = 'on';
        app.NextButton.Visible = 'on';
        app.PreviousButton.Visible = 'on';
        app.XDropDown.Visible = 'on';
        app.YDropDown.Visible = 'on';
        app.XDropDown.Visible = 'on';
        app.YDropDown.Visible = 'on';
        app.HistogramAxes.Visible = 'on';
        app.XFeatureLabel_2.Visible = 'on';
        app.YFeatureLabel_2.Visible = 'on';
        app.Segment_Button.Visible = 'on';


        PlotHistogram(app);

        app.PlotIndex.Value = double(app.CurrentImageIndex);
    end

        end

        % Value changed function: XDropDown, YDropDown
        function PlotHistogram(app, event)
            xFeature = app.XDropDown.Value;
            yFeature = app.YDropDown.Value;

            if isempty(app.AnalysisData)
                return;
            end

            % Extract the selected feature's values
            if strcmp(app.PlotMode.Value,'Aggregate')
                featureData = app.AggregateAnalysis;
            elseif strcmp(app.PlotMode.Value,'Individual')
                featureData = app.AnalysisData{app.CurrentImageIndex};
            end


            % Decide plot type
            if  strcmp(yFeature, 'Frequency')
                
                % Plot histogram
                histogram(app.HistogramAxes,featureData.(xFeature));
                xlabel(app.HistogramAxes, xFeature);
                ylabel(app.HistogramAxes, 'Frequency');
            else
                % Scatter plot view
                xValues = featureData.(xFeature);
                yValues = featureData.(yFeature);

        % Create scatter plot
        scatter(app.HistogramAxes, xValues, yValues, 'filled');
        xlabel(app.HistogramAxes, xFeature);
        ylabel(app.HistogramAxes, yFeature);

        app.Number_of_Cells_Field.Value = length(app.Scores{app.CurrentImageIndex});


            end
        end

        % Button pushed function: PreviousButton
        function ToggleSwitchValueChanged(app, event)
            if isempty(app.AnalysisData)
                return
            elseif app.CurrentImageIndex == 1
                app.CurrentImageIndex = size(app.AnalysisData, 2);
            else
                app.CurrentImageIndex = app.CurrentImageIndex -1;
            end
            app.PlotIndex.Value = double(app.CurrentImageIndex);
            PlotHistogram(app);
            UpdateIm(app);

        end

        % Button pushed function: Settings_Button
        function Settings_ButtonPushed(app, event)
    if strcmp(app.Panel.Visible, 'on')
        app.Panel.Visible = 'off';
    else
        app.Panel.Visible = 'on';
    end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Panel.Visible = 'off';

        end

        % Value changed function: DenoiseSwitch
        function DenoiseSwitchValueChanged(app, event)
            if strcmp(app.DenoiseSwitch.Value, 'Off')
                app.DWTLEv.Visible = "off";
                app.DWTThresh_t.Visible = "off";
                app.LevelEditFieldLabel.Visible = "off";
                app.LevelEditFieldLabel_2.Visible = "off";
                app.DWTThresh.Visible = 'off';
                app.Label_19.Visible = 'off';
            else
                app.DWTLEv.Visible = "on";
                app.DWTThresh_t.Visible = "on";
                app.LevelEditFieldLabel.Visible = "on";
                app.LevelEditFieldLabel_2.Visible = "on";
                app.DWTThresh.Visible = 'on';
                app.Label_19.Visible = 'on';

            end
        end

        % Value changed function: TrackingOption
        function TrackingOptionValueChanged2(app, event)
             if strcmp(app.TrackingOption.Value, 'Off')
                app.MinIOUTrack.Visible = 'off';
                app.IOUTrackText.Visible = "off";
                app.RPNOptAlphaText.Visible = 'off';
                app.Alpha.Visible = 'off';
                app.LevelEditFieldLabel_6.Visible = 'off';
                app.LevelEditFieldLabel_7.Visible = 'off';
                app.Label_11.Visible = 'off';
                app.Label_13.Visible = 'off';
            else
                app.MinIOUTrack.Visible = 'on';
                app.IOUTrackText.Visible = "on";
                app.RPNOptAlphaText.Visible = 'on';
                app.Alpha.Visible = 'on';
                app.LevelEditFieldLabel_6.Visible = 'on';
                app.LevelEditFieldLabel_7.Visible = 'on';
                app.Label_11.Visible = 'on';
                app.Label_13.Visible = 'on';
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.PauseButton.Visible = 'off';
            uiwait(app.UIFigure);
        end

        % Button pushed function: DefaultButton
        function DefaultButtonPushed(app, event)
       highlightButton(app, app.DefaultButton);
       app.OverlaPP.Value = 30;
       app.OverlapProp.Value = 30;
       app.Confidence.Value = 50;
       app.Alpha.Value = 15;
       app.DWTLEv.Value = 4;
       app.ObjectSize.Value = [15 200];
       app.MinIOUTrack.Value = 5;
       app.DenoiseSwitch.Value = 'On';
       app.MaxProposals.Value = 2500;
       app.DWTThresh.Value = 2;

       app.OverlaPP_t.Value = app.OverlaPP.Value;
       app.OverlapProp_t.Value = app.OverlapProp.Value;
       app.ConfidenceField.Value = app.Confidence.Value;
       app.ConfidenceField.Value = app.Confidence.Value;
       app.RPNOptAlphaText.Value = app.Alpha.Value;
       app.SizeMin.Value = app.ObjectSize.Value(1);
       app.SizeMax.Value = app.ObjectSize.Value(2);
       app.IOUTrackText.Value = app.MinIOUTrack.Value;
       app.DWTThresh_t.Value = app.DWTThresh.Value;
        end

        % Button pushed function: ConservativeButton
        function ConservativeButtonPushed(app, event)
        highlightButton(app, app.ConservativeButton);
       app.OverlaPP.Value = 25;
       app.OverlapProp.Value = 25;
       app.Confidence.Value = 75;
       app.Alpha.Value = 5;
       app.DWTLEv.Value = 4;
       app.ObjectSize.Value = [25 150];
       app.MinIOUTrack.Value = 10;
       app.DenoiseSwitch.Value = 'On';
       app.MaxProposals.Value = 2500;
       app.DWTThresh.Value = 2;


       app.OverlaPP_t.Value = app.OverlaPP.Value;
       app.OverlapProp_t.Value = app.OverlapProp.Value;
       app.ConfidenceField.Value = app.Confidence.Value;
       app.ConfidenceField.Value = app.Confidence.Value;
       app.RPNOptAlphaText.Value = app.Alpha.Value;
       app.SizeMin.Value = app.ObjectSize.Value(1);
       app.SizeMax.Value = app.ObjectSize.Value(2);
       app.IOUTrackText.Value = app.MinIOUTrack.Value;
       app.DWTThresh_t.Value = app.DWTThresh.Value;

        end

        % Button pushed function: RelaxedButton
        function RelaxedButtonPushed(app, event)
       highlightButton(app, app.RelaxedButton);
       app.OverlaPP.Value = 75;
       app.OverlapProp.Value = 75;
       app.Confidence.Value = 25;
       app.Alpha.Value = 20;
       app.DWTLEv.Value = 4;
       app.ObjectSize.Value = [2 250];
       app.MinIOUTrack.Value = 2;
       app.DenoiseSwitch.Value = 'On';
       app.MaxProposals.Value = 5000;
       app.DWTThresh.Value = 2;


       app.OverlaPP_t.Value = app.OverlaPP.Value;
       app.OverlapProp_t.Value = app.OverlapProp.Value;
       app.ConfidenceField.Value = app.Confidence.Value;
       app.ConfidenceField.Value = app.Confidence.Value;
       app.RPNOptAlphaText.Value = app.Alpha.Value;
       app.SizeMin.Value = app.ObjectSize.Value(1);
       app.SizeMax.Value = app.ObjectSize.Value(2);
       app.IOUTrackText.Value = app.MinIOUTrack.Value;
       app.DWTThresh_t.Value = app.DWTThresh.Value;

        end

        % Value changed function: PlotMode
        function PlotModeValueChanged(app, event)
            PlotHistogram(app);
        end

        % Value changed function: PlotIndex
        function PlotIndexValueChanged(app, event)
            value = app.PlotIndex.Value;
            value = ceil(value);

            if app.AnalysisData == []
                value = 1;
            elseif value > size(app.AnalysisData, 2)
                value = size(app.AnalysisData, 2);
            end
            
            app.PlotIndex.Value = value;
            app.CurrentImageIndex = value;   

            UpdateIm(app);
            PlotHistogram(app);

        end

        % Value changed function: OverlaPP
        function OverlaPPValueChanged(app, event)
            value = app.OverlaPP.Value;
            if value>=100
                value = 100;
            elseif value <0
                value = 0.01;
            end
            app.OverlaPP.Value = value;
            app.OverlaPP_t.Value = value;
        end

        % Value changed function: OverlapProp_t
        function OverlapProp_tValueChanged(app, event)
            value = app.OverlapProp_t.Value;
            if value>=100
                value = 100;
            elseif value <0
                value = 0.01;
            end
            app.OverlaPP.Value = value;
            app.OverlaPP_t.Value = value;
        end

        % Value changed function: OverlapProp
        function OverlapPropValueChanged(app, event)
            value = app.OverlapProp.Value;
            if value>=100
                value = 100;
            elseif value <0
                value = 0.01;
            end
            app.OverlapProp.Value = value;
            app.OverlapProp_t.Value = value;
        end

        % Value changed function: OverlaPP_t
        function OverlaPP_tValueChanged(app, event)
            value = app.OverlaPP_t.Value;
            if value>=100
                value = 100;
            elseif value <0
                value = 0.01;
            end
            app.OverlaPP.Value = value;
            app.OverlaPP_t.Value = value;
        end

        % Value changed function: Confidence
        function ConfidenceValueChanged(app, event)
            value = app.Confidence.Value;
            if value>=100
                value = 100;
            elseif value <0
                value = 0.01;
            end
            app.Confidence.Value = value;
            app.ConfidenceField.Value = value;
                
            if app.SingleImage ==1
             app.Scores{1} = app.SingleScores(app.SingleScores>app.Confidence.Value/100);
             app.Labels{1} = app.SingleLabels(app.SingleScores>app.Confidence.Value/100);
             app.Masks{1}  = app.SingleMasks(:, :,app.SingleScores>app.Confidence.Value/100);
             app.Boxes{1}  = app.SingleBoxes(app.SingleScores>app.Confidence.Value/100, :);
             app.AnalysisData{1} = ExtractFeatures(app.Images{1}, app.Masks{1}, app.Boxes{1}, app.Scores{1}, 1);

            DispColors = 1.4 - 0.5*rescale(app.Scores{1})+0.05*rand([length(app.Scores{1}) 1]);
            DispColors = DispColors .* [0.466 0.674	0.188];
            app.SegmentedImages = insertObjectMask(app.Images{1}, app.Masks{1}, Color=DispColors );
            app.Number_of_Cells_Field.Value = length(app.Scores{1});

            IMGDisplay(app, app.SegmentedImages, app.ImageDisp); drawnow;
            PlotHistogram(app);
            end


        end

        % Value changed function: ConfidenceField
        function ConfidenceFieldValueChanged(app, event)
            value = app.ConfidenceField.Value;
            if value>=100
                value = 100;
            elseif value <=0
                value = 1e-7;
            end
            app.Confidence.Value = value;
            app.ConfidenceField.Value = value;

            if app.SingleImage ==1
             app.Scores{1} = app.SingleScores(app.SingleScores>app.Confidence.Value/100);
             app.Labels{1} = app.SingleLabels(app.SingleScores>app.Confidence.Value/100);
             app.Masks{1}  = app.SingleMasks(:, :,app.SingleScores>app.Confidence.Value/100);
             app.Boxes{1}  = app.SingleBoxes(app.SingleScores>app.Confidence.Value/100, :);
             app.AnalysisData{1} = ExtractFeatures(app.Images{1}, app.Masks{1}, app.Boxes{1}, app.Scores{1}, 1);
            
            DispColors = 1.4 - 0.5*rescale(app.Scores{1})+0.05*rand([length(app.Scores{1}) 1]);
            DispColors = DispColors .* [0.466 0.674	0.188];
            app.SegmentedImages = insertObjectMask(app.Images{1}, app.Masks{1}, Color=DispColors );
            app.Number_of_Cells_Field.Value = length(app.Scores{1});
            IMGDisplay(app, app.SegmentedImages, app.ImageDisp); drawnow;
            end


        end

        % Value changing function: Confidence
        function ConfidenceValueChanging(app, event)
            changingValue = event.Value;
            if changingValue>=100
                changingValue = 100;
            elseif changingValue <=0
                changingValue = 1e-5;
            end
            app.Confidence.Value = changingValue;
            app.ConfidenceField.Value = changingValue;

            if app.SingleImage ==1
            app.Masks{1}  = app.SingleMasks(:, :,app.SingleScores>app.Confidence.Value/100);
            app.Scores{1} = app.SingleScores(app.SingleScores>app.Confidence.Value/100);

            DispColors = 1.4 - 0.5*rescale(app.Scores{1})+0.05*rand([length(app.Scores{1}) 1]);
            DispColors = DispColors .* [0.466 0.674	0.188];
            app.SegmentedImages = insertObjectMask(app.Images{1}, app.Masks{1}, Color=DispColors );
            app.Number_of_Cells_Field.Value = length(app.Scores{1});

            IMGDisplay(app, app.SegmentedImages, app.ImageDisp); drawnow;
            end

           
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            load([value+".mat"]);
            app.net = net;
            
        end

        % Value changed function: DWTLEv
        function DWTLEvValueChanged(app, event)
            value = app.DWTLEv.Value;
            app.DWTLEv.Value = ceil(value);
            

        end

        % Value changed function: DWTThresh_t
        function DWTThresh_tValueChanged(app, event)
            value = app.DWTThresh_t.Value;
            if value < 0 
                value =0;
            elseif value > 10
                value =10;
            end
            app.DWTThresh.Value = value;
            app.DWTThresh_t.Value = value;
        end

        % Value changed function: IOUTrackText
        function IOUTrackTextValueChanged(app, event)
            value = app.IOUTrackText.Value;
            if value < 0
                value = 0.001;
            elseif value > 20 
                value = 20;
            end
             app.IOUTrackText.Value = value;
             app.MinIOUTrack.Value = value;
        end

        % Value changed function: MinIOUTrack
        function MinIOUTrackValueChanged(app, event)
            value = app.MinIOUTrack.Value;
            if value < 0
                value = 0.001;
            elseif value > 20 
                value = 20;
            end
             app.IOUTrackText.Value = value;
             app.MinIOUTrack.Value = value;
        end

        % Value changed function: Alpha
        function AlphaValueChanged(app, event)
            value = app.Alpha.Value;
            if value < 0
                value = 0.001;
            elseif value > 30 
                value = 30;
            end
             app.Alpha.Value = value;
             app.RPNOptAlphaText.Value = value;
        end

        % Value changed function: RPNOptAlphaText
        function RPNOptAlphaTextValueChanged(app, event)
            value = app.RPNOptAlphaText.Value;
            if value < 0
                value = 0.001;
            elseif value > 30 
                value = 30;
            end
             app.Alpha.Value = value;
             app.RPNOptAlphaText.Value = value;
        end

        % Value changed function: ObjectSize
        function ObjectSizeValueChanged(app, event)
            value = app.ObjectSize.Value;
            value = ceil(value);
            if value(1) <= 1
                value(1) = 1;
            end
            if value(2) <= value(1)
                value(2) = value(1)+1;
            end
            app.ObjectSize.Value = value;
            app.SizeMin.Value = value(1);
            app.SizeMax.Value = value(2);

        end

        % Value changed function: DWTThresh
        function DWTThreshValueChanged(app, event)
            value = app.DWTThresh.Value;
            if value < 0 
                value =0;
            elseif value > 10
                value =10;
            end
            app.DWTThresh.Value = value;
            app.DWTThresh_t.Value = value;
        end

        % Value changed function: MaxProposals
        function MaxProposalsValueChanged(app, event)
            value = app.MaxProposals.Value;
            if value<500
                value = 500;
            end
            value = ceil(value);
            app.MaxProposals.Value = value;
        end

        % Button pushed function: NextButton
        function NextButtonPushed2(app, event)
            if isempty(app.AnalysisData)
                return
            elseif app.CurrentImageIndex == size(app.AnalysisData, 2)
                app.CurrentImageIndex = 1;
            else
                app.CurrentImageIndex = app.CurrentImageIndex + 1;
            end
            app.PlotIndex.Value = double(app.CurrentImageIndex);
            UpdateIm(app);
            PlotHistogram(app);
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
    % Clear loaded images and related data
    app.Images = {};
    app.Filenames = {};
    app.SegmentedImages = {};
    app.Masks = {};
    app.Boxes = {};
    app.Labels = {};
    app.Scores = {};
    app.AnalysisData = {};
    app.CurrentImageIndex = [];

  
    app.CurrentImageIndex = 1;
    app.SegmentedImages = []; % Stores the segmented image
    app.SingleMasks = [];
    app.SingleBoxes = [];
    app.SingleScores = [];
    app.SingleLabels = [];
    app.AnalysisData = [];
    app.Progress = 0; 
    UpdateProgress(app);

    app.TrackingInfo = [];
    app.SingleImage = 0; 
    app.AggregateAnalysis = [];
    app.Tracks = [];
    % Reset Image display to default grey screen
    IMGDisplay(app, ones(520, 740)*0.9, app.ImageDisp);

    % Reset mode to default if needed
    app.PlotMode.Value = 'Individual';

    % Hide/Reset UI elements
    app.InitialLabel.Visible = 'on';
    app.Segment_Button.Visible = 'off';
    app.NextButton.Visible = 'off';
    app.PreviousButton.Visible = 'off';
    app.ExportData_Button.Visible = 'off';
    app.HistogramAxes.Visible = 'off';
    app.Number_of_Cells_Field.Visible = 'off';
    app.Number_of_Cells_Text.Visible = 'off';
    app.PlotFeaturesText.Visible = 'off';
    app.PlotMode.Visible = 'off';
    app.XDropDown.Visible = 'off';
    app.YDropDown.Visible = 'off';
    app.XDropDown.Visible = 'off';
    app.YDropDown.Visible = 'off';
    app.XFeatureLabel_2.Visible = 'off';
    app.YFeatureLabel_2.Visible = 'off';
    app.PlotIndex.Visible = 'off';
    app.ExportPanel.Visible = 'off';
    
    cla(app.HistogramAxes);
    app.Number_of_Cells_Field.Value = 0;


    UpdateProgress(app); % Reset progress value
    DefaultButtonPushed(app); % Re-apply default model button
    drawnow;
        end

        % Button pushed function: ExportData_Button
        function ExportData_ButtonPushed(app, event)
    if strcmp(app.ExportPanel.Visible, 'on')
        app.ExportPanel.Visible = 'off';
    else
        app.ExportPanel.Visible = 'on';
    end
        end

        % Button pushed function: CloseButton_2
        function CloseButton_2Pushed(app, event)
        app.ExportPanel.Visible = 'off';
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
    % Ask user to choose folder
    exportDir = uigetdir(pwd, 'Select Export Folder');
    
    if exportDir == 0
        return; % User cancelled
    end

    % Export CSVs if checked
    if app.CSVFileCheckBox.Value
        try
            
            if ~isempty(app.TrackingInfo)
                writetable(app.TrackingInfo, fullfile(exportDir, 'TrackingInfo.csv'));
            end
            writetable(app.AggregateAnalysis, fullfile(exportDir, 'FeatureTable.csv'));
        catch ME
            uialert(app.UIFigure, ['Failed to export CSV: ' ME.message], 'Export Error');
            return;
        end
    end

    if app.matFileCheckBox.Value | app.JSONFileCheckBox.Value
    % Export .mat file if checked
    Images = app.Images;
    Masks = app.Masks;
    Boxes = app.Boxes;
    Labels = app.Labels;
    Scores = app.Scores;
    Filenames = app.Filenames;
    AnalysisData = app.AnalysisData;

    TrackingInfo = app.TrackingInfo;
    Tracks = app.Tracks;
    FeatureTable = app.AggregateAnalysis;

    if app.matFileCheckBox.Value
        try
            save(fullfile(exportDir, 'ExportedData.mat'), ...
                'Images', 'Masks', 'Boxes', 'Labels', 'Scores', ...
                 'Filenames','Tracks', 'AnalysisData', ...
                'TrackingInfo', 'FeatureTable');
        catch ME
            uialert(app.UIFigure, ['Failed to export MAT: ' ME.message], 'Export Error');
            return;
        end
        if app.JSONFileCheckBox.Value
        mkdir(fullfile(exportDir, "JSONExports"));

    Images = jsonencode(Images);
    ImagesID = fopen(fullfile(exportDir,"JSONExports","Images.JSON"),"w");
    fprintf(ImagesID, Images);

    Masks = jsonencode(Masks);
    MasksID = fopen(fullfile(exportDir,"JSONExports","Masks.JSON"),"w");
    fprintf(MasksID, Masks);

    Boxes = jsonencode(Boxes);
    BoxesID = fopen(fullfile(exportDir,"JSONExports","Boxes.JSON"),"w");
    fprintf(BoxesID, Boxes);

    Labels = jsonencode(Labels);
    LabelsID = fopen(fullfile(exportDir,"JSONExports","Labels.JSON"),"w");
    fprintf(LabelsID, Labels);
    
    Scores = jsonencode(Scores);
    ScoreID = fopen(fullfile(exportDir,"JSONExports","Scores.JSON"),"w");
    fprintf(ScoreID, Scores);
    
    Filenames = jsonencode(Filenames);
    FilenamesID = fopen(fullfile(exportDir,"JSONExports","Filenames.JSON"),"w");
    fprintf(FilenamesID, Filenames);

    AnalysisData = jsonencode(AnalysisData);
    AnalysisDataID = fopen(fullfile(exportDir,"JSONExports","AnalysisTable.JSON"),"w");
    fprintf(AnalysisDataID, AnalysisData);

    Tracks = jsonencode(Tracks);
    TracksID = fopen(fullfile(exportDir,"JSONExports","Tracks!.JSON"),"w");
    fprintf(TracksID, Tracks);


        end
    end

    % Optional: show success message once export is complete
    % app.ExportStatusLabel.Text = 'Export complete!';
    end
        end

        % Value changed function: SizeMin
        function SizeMinValueChanged(app, event)
            
            value = app.SizeMin.Value;
            value = ceil(value);

             if value <= 1
                value = 1;
            end
          
            app.ObjectSize.Value(1) = value;
            app.SizeMin.Value = value;

        end

        % Value changed function: SizeMax
        function SizeMaxValueChanged(app, event)
            value = app.SizeMax.Value;
            value = ceil(value);

            if value > 250
                value = 250;
            end
          
            app.ObjectSize.Value(2) = value;
            app.SizeMax.Value = value;
        end

        % Button pushed function: Help_Button
        function Help_ButtonPushed(app, event)
            web("https://github.com/TechAvi-eng/Multires-ML-Microscopy/blob/main/App%20User%20Guide.pdf");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'turbo');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramAxes = uiaxes(app.UIFigure);
            ylabel(app.HistogramAxes, 'Frequency')
            colormap(app.HistogramAxes, 'turbo')
            app.HistogramAxes.Visible = 'off';
            app.ProgressBarAxes = uiaxes(app.UIFigure);
            app.UploadImagesButton = uibutton(app.UIFigure, 'push');
            app.UploadImagesButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImage, true);
            app.UploadImagesButton.Text = 'Select Images';
            app.Settings_Button = uibutton(app.UIFigure, 'push');
            app.Settings_Button.ButtonPushedFcn = createCallbackFcn(app, @Settings_ButtonPushed, true);
            app.Settings_Button.Icon = fullfile(pathToMLAPP, 'settings.png');
            app.Settings_Button.Tooltip = {'Settings'};
            app.Settings_Button.Text = '';
            app.Help_Button = uibutton(app.UIFigure, 'push');
            app.Help_Button.ButtonPushedFcn = createCallbackFcn(app, @Help_ButtonPushed, true);
            app.Help_Button.Tooltip = {'Help (Opens User Guide)'};
            app.Help_Button.Text = '?';
            app.Segment_Button = uibutton(app.UIFigure, 'push');
            app.Segment_Button.ButtonPushedFcn = createCallbackFcn(app, @Segment, true);
            app.Segment_Button.Visible = 'off';
            app.Segment_Button.Text = 'Segment';
            app.Number_of_Cells_Text = uilabel(app.UIFigure);
            app.Number_of_Cells_Text.Visible = 'off';
            app.Number_of_Cells_Text.Text = 'Number of Cells ';
            app.Number_of_Cells_Field = uieditfield(app.UIFigure, 'numeric');
            app.Number_of_Cells_Field.Limits = [0 Inf];
            app.Number_of_Cells_Field.RoundFractionalValues = 'on';
            app.Number_of_Cells_Field.AllowEmpty = 'on';
            app.Number_of_Cells_Field.Editable = 'off';
            app.Number_of_Cells_Field.Visible = 'off';
            app.YDropDown = uidropdown(app.UIFigure);
            app.YDropDown.Items = {'Frequency', 'Area', 'Perimeter', 'Aspect Ratio', 'Mean Intensity', 'Standard Deviation', 'Eccentricity', 'Circularity', 'Solidity', 'Max Diameter', 'Kurtosis'};
            app.YDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotHistogram, true);
            app.YDropDown.Visible = 'off';
            app.YDropDown.Value = 'Perimeter';
            app.XDropDown = uidropdown(app.UIFigure);
            app.XDropDown.Items = {'Area', 'Perimeter', 'Aspect Ratio', 'Mean Intensity', 'Standard Deviation', 'Eccentricity', 'Circularity', 'Solidity', 'Max Diameter', 'Kurtosis'};
            app.XDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotHistogram, true);
            app.XDropDown.Visible = 'off';
            app.XDropDown.Value = 'Area';
            app.Credit = uihyperlink(app.UIFigure);
            app.Credit.URL = 'https://github.com/TechAvi-eng/Multires-ML-Microscopy';
            app.Credit.Text = 'Multires ML Microscopy';
            app.ExportData_Button = uibutton(app.UIFigure, 'push');
            app.ExportData_Button.ButtonPushedFcn = createCallbackFcn(app, @ExportData_ButtonPushed, true);
            app.ExportData_Button.Icon = fullfile(pathToMLAPP, 'export.png');
            app.ExportData_Button.Visible = 'off';
            app.ExportData_Button.Tooltip = {'Export Data'};
            app.ExportData_Button.Text = '';
            app.ImageDisp = uiimage(app.UIFigure);
            app.ImageDisp.ImageClickedFcn = createCallbackFcn(app, @UploadImage, true);
            app.Reset = uibutton(app.UIFigure, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Icon = fullfile(pathToMLAPP, 'reset2.jpg');
            app.Reset.Text = '';
            app.PlotMode = uiswitch(app.UIFigure, 'slider');
            app.PlotMode.Items = {'Aggregate', 'Individual'};
            app.PlotMode.ValueChangedFcn = createCallbackFcn(app, @PlotModeValueChanged, true);
            app.PlotMode.Visible = 'off';
            app.PlotMode.Tooltip = {'Plot values for a single image or across the entire dataset'};
            app.PlotMode.Value = 'Individual';
            app.PreviousButton = uibutton(app.UIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleSwitchValueChanged, true);
            app.PreviousButton.Icon = fullfile(pathToMLAPP, 'previousbutton.png');
            app.PreviousButton.Visible = 'off';
            app.PreviousButton.Tooltip = {'Previous Image'};
            app.PreviousButton.Text = '';
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed2, true);
            app.NextButton.Icon = fullfile(pathToMLAPP, 'nextbutton.png');
            app.NextButton.Visible = 'off';
            app.NextButton.Tooltip = {'Next Image'};
            app.NextButton.Text = '';
            app.PlotFeaturesText = uilabel(app.UIFigure);
            app.PlotFeaturesText.Visible = 'off';
            app.PlotFeaturesText.Text = 'Plot Features:';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = fullfile(pathToMLAPP, 'stop2.png');
            app.PauseButton.Visible = 'off';
            app.PauseButton.Tooltip = {'Cancel Current Segmentation'};
            app.PauseButton.Text = '';
            app.PlotIndex = uieditfield(app.UIFigure, 'numeric');
            app.PlotIndex.Limits = [1 Inf];
            app.PlotIndex.ValueDisplayFormat = '%11.0g';
            app.PlotIndex.ValueChangedFcn = createCallbackFcn(app, @PlotIndexValueChanged, true);
            app.PlotIndex.Visible = 'off';
            app.PlotIndex.Value = 1;
            app.XFeatureLabel_2 = uilabel(app.UIFigure);
            app.XFeatureLabel_2.Visible = 'off';
            app.XFeatureLabel_2.Text = 'X ';
            app.YFeatureLabel_2 = uilabel(app.UIFigure);
            app.YFeatureLabel_2.Visible = 'off';
            app.YFeatureLabel_2.Text = 'Y';
            app.InitialLabel = uilabel(app.UIFigure);
            app.InitialLabel.Text = 'Please select images to start.';
            app.ExportPanel = uipanel(app.UIFigure);
            app.ExportPanel.Visible = 'off';
            app.ExportDataLabel = uilabel(app.ExportPanel);
            app.ExportDataLabel.Text = 'Export Data';
            app.CloseButton_2 = uibutton(app.ExportPanel, 'push');
            app.CloseButton_2.ButtonPushedFcn = createCallbackFcn(app, @CloseButton_2Pushed, true);
            app.CloseButton_2.Icon = fullfile(pathToMLAPP, 'close.png');
            app.CloseButton_2.Text = '';
            app.CSVFileCheckBox = uicheckbox(app.ExportPanel);
            app.CSVFileCheckBox.Text = '   CSV File';
            app.CSVFileCheckBox.Value = true;
            app.matFileCheckBox = uicheckbox(app.ExportPanel);
            app.matFileCheckBox.Text = '   .mat File';
            app.ExportButton = uibutton(app.ExportPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.JSONFileCheckBox = uicheckbox(app.ExportPanel);
            app.JSONFileCheckBox.Text = '  JSON File';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.SettingsLabel = uilabel(app.Panel);
            app.SettingsLabel.Text = 'Settings';
            app.PresetLabel = uilabel(app.Panel);
            app.PresetLabel.Tooltip = {'Presets mainly affect the false positive and negative rates'};
            app.PresetLabel.Text = 'Preset';
            app.DefaultButton = uibutton(app.Panel, 'push');
            app.DefaultButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultButtonPushed, true);
            app.DefaultButton.Text = 'Default';
            app.ConservativeButton = uibutton(app.Panel, 'push');
            app.ConservativeButton.ButtonPushedFcn = createCallbackFcn(app, @ConservativeButtonPushed, true);
            app.ConservativeButton.Text = 'Conservative';
            app.RelaxedButton = uibutton(app.Panel, 'push');
            app.RelaxedButton.ButtonPushedFcn = createCallbackFcn(app, @RelaxedButtonPushed, true);
            app.RelaxedButton.Text = 'Relaxed';
            app.ConfidenceThresholdLabel = uilabel(app.Panel);
            app.ConfidenceThresholdLabel.Tooltip = {'The minimum confidence to display a result'};
            app.ConfidenceThresholdLabel.Text = {'Confidence'; 'Threshold'};
            app.Confidence = uislider(app.Panel);
            app.Confidence.MajorTicks = [0 100];
            app.Confidence.ValueChangedFcn = createCallbackFcn(app, @ConfidenceValueChanged, true);
            app.Confidence.ValueChangingFcn = createCallbackFcn(app, @ConfidenceValueChanging, true);
            app.ConfidenceField = uieditfield(app.Panel, 'numeric');
            app.ConfidenceField.Limits = [0 100];
            app.ConfidenceField.ValueDisplayFormat = '%5.0f';
            app.ConfidenceField.ValueChangedFcn = createCallbackFcn(app, @ConfidenceFieldValueChanged, true);
            app.ConfidenceField.Placeholder = '20%';
            app.ConfidenceField.Value = 50;
            app.AdvancedLabel = uilabel(app.Panel);
            app.AdvancedLabel.Text = 'Advanced ';
            app.PreProcessingLabel = uilabel(app.Panel);
            app.PreProcessingLabel.Text = 'Pre-Processing';
            app.LevelEditFieldLabel = uilabel(app.Panel);
            app.LevelEditFieldLabel.Text = 'Level';
            app.DWTLEv = uieditfield(app.Panel, 'numeric');
            app.DWTLEv.Limits = [1 8];
            app.DWTLEv.ValueChangedFcn = createCallbackFcn(app, @DWTLEvValueChanged, true);
            app.DWTLEv.Tooltip = {'Level of decomposition for de-noising: higher levels remove more noise at the cost of performance'};
            app.DWTLEv.Value = 4;
            app.DWTThresh = uislider(app.Panel);
            app.DWTThresh.Limits = [0 10];
            app.DWTThresh.MajorTicks = [0 10];
            app.DWTThresh.MajorTickLabels = {'0', '10'};
            app.DWTThresh.ValueChangedFcn = createCallbackFcn(app, @DWTThreshValueChanged, true);
            app.DWTThresh.Tooltip = {''};
            app.LevelEditFieldLabel_2 = uilabel(app.Panel);
            app.LevelEditFieldLabel_2.Tooltip = {'Tunes de-noising: larger values remove more noise at the expense of image quality'};
            app.LevelEditFieldLabel_2.Text = 'Threshold';
            app.SegmentationNetworkLabel = uilabel(app.Panel);
            app.SegmentationNetworkLabel.Text = 'Segmentation Network';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'EfficientNet', 'ResNet50', 'ResNet101', 'CascadeEfficientNet'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'EfficientNet';
            app.OverlaPP = uislider(app.Panel);
            app.OverlaPP.MajorTicks = [0 100];
            app.OverlaPP.ValueChangedFcn = createCallbackFcn(app, @OverlaPPValueChanged, true);
            app.OverlaPP.Tooltip = {''};
            app.OverlapProp = uislider(app.Panel);
            app.OverlapProp.MajorTicks = [0 100];
            app.OverlapProp.ValueChangedFcn = createCallbackFcn(app, @OverlapPropValueChanged, true);
            app.OverlapProp.Tooltip = {''};
            app.LevelEditFieldLabel_3 = uilabel(app.Panel);
            app.LevelEditFieldLabel_3.Tooltip = {'Maximum overlap allowed between cells'};
            app.LevelEditFieldLabel_3.Text = 'Overlap';
            app.LevelEditFieldLabel_4 = uilabel(app.Panel);
            app.LevelEditFieldLabel_4.Tooltip = {'Maximum overlap allowed between proposals: reduces processing time, but may miss adjacent cells'};
            app.LevelEditFieldLabel_4.Text = {'Proposal'; 'Overlap'};
            app.OverlaPP_t = uieditfield(app.Panel, 'numeric');
            app.OverlaPP_t.ValueDisplayFormat = '%11.0f';
            app.OverlaPP_t.ValueChangedFcn = createCallbackFcn(app, @OverlaPP_tValueChanged, true);
            app.OverlaPP_t.Tooltip = {''};
            app.OverlaPP_t.Value = 30;
            app.OverlapProp_t = uieditfield(app.Panel, 'numeric');
            app.OverlapProp_t.Limits = [0 100];
            app.OverlapProp_t.ValueDisplayFormat = '%11.0f';
            app.OverlapProp_t.ValueChangedFcn = createCallbackFcn(app, @OverlapProp_tValueChanged, true);
            app.OverlapProp_t.Tooltip = {''};
            app.OverlapProp_t.Value = 30;
            app.LevelEditFieldLabel_5 = uilabel(app.Panel);
            app.LevelEditFieldLabel_5.Text = 'Maximum Proposals';
            app.MaxProposals = uieditfield(app.Panel, 'numeric');
            app.MaxProposals.Limits = [500 Inf];
            app.MaxProposals.ValueDisplayFormat = '%11.0f';
            app.MaxProposals.ValueChangedFcn = createCallbackFcn(app, @MaxProposalsValueChanged, true);
            app.MaxProposals.Tooltip = {''};
            app.MaxProposals.Value = 2500;
            app.DenoiseSwitch = uiswitch(app.Panel, 'slider');
            app.DenoiseSwitch.ValueChangedFcn = createCallbackFcn(app, @DenoiseSwitchValueChanged, true);
            app.DenoiseSwitch.Tooltip = {'Enable de-noising of images (recommended)'};
            app.DenoiseSwitch.Value = 'On';
            app.TrackingLabel_2 = uilabel(app.Panel);
            app.TrackingLabel_2.Text = 'Tracking';
            app.LevelEditFieldLabel_6 = uilabel(app.Panel);
            app.LevelEditFieldLabel_6.Visible = 'off';
            app.LevelEditFieldLabel_6.Tooltip = {'Minimum overlap needed for two predictions to be tracked as one object'};
            app.LevelEditFieldLabel_6.Text = 'Min Overlap';
            app.LevelEditFieldLabel_7 = uilabel(app.Panel);
            app.LevelEditFieldLabel_7.Visible = 'off';
            app.LevelEditFieldLabel_7.Tooltip = {'Speed up processing during tracking by pruning proposals at the expense of recall'};
            app.LevelEditFieldLabel_7.Text = {'RPN '; 'Optimization '; 'Factor'};
            app.Alpha = uislider(app.Panel);
            app.Alpha.Limits = [0 30];
            app.Alpha.MajorTicks = [0 30];
            app.Alpha.MajorTickLabels = {'0', '30'};
            app.Alpha.ValueChangedFcn = createCallbackFcn(app, @AlphaValueChanged, true);
            app.Alpha.Visible = 'off';
            app.Alpha.Tooltip = {'Minimum overlap needed for two predictions to be tracked as one object'};
            app.RPNOptAlphaText = uieditfield(app.Panel, 'numeric');
            app.RPNOptAlphaText.Limits = [0 30];
            app.RPNOptAlphaText.ValueDisplayFormat = '%11.0f';
            app.RPNOptAlphaText.ValueChangedFcn = createCallbackFcn(app, @RPNOptAlphaTextValueChanged, true);
            app.RPNOptAlphaText.Visible = 'off';
            app.RPNOptAlphaText.Tooltip = {'Minimum overlap needed for two predictions to be tracked as one object'};
            app.RPNOptAlphaText.Value = 15;
            app.IOUTrackText = uieditfield(app.Panel, 'numeric');
            app.IOUTrackText.Limits = [0 20];
            app.IOUTrackText.ValueDisplayFormat = '%11.0f';
            app.IOUTrackText.ValueChangedFcn = createCallbackFcn(app, @IOUTrackTextValueChanged, true);
            app.IOUTrackText.Visible = 'off';
            app.IOUTrackText.Tooltip = {'Minimum overlap needed for two predictions to be tracked as one object'};
            app.IOUTrackText.Value = 5;
            app.MinIOUTrack = uislider(app.Panel);
            app.MinIOUTrack.Limits = [0 20];
            app.MinIOUTrack.MajorTicks = [0 20];
            app.MinIOUTrack.ValueChangedFcn = createCallbackFcn(app, @MinIOUTrackValueChanged, true);
            app.MinIOUTrack.Visible = 'off';
            app.MinIOUTrack.Tooltip = {'Minimum overlap needed for two predictions to be tracked as one object'};
            app.DWTThresh_t = uieditfield(app.Panel, 'numeric');
            app.DWTThresh_t.Limits = [0 10];
            app.DWTThresh_t.ValueDisplayFormat = '%11.0f';
            app.DWTThresh_t.ValueChangedFcn = createCallbackFcn(app, @DWTThresh_tValueChanged, true);
            app.DWTThresh_t.Value = 2;
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Icon = fullfile(pathToMLAPP, 'close.png');
            app.CloseButton.Text = '';
            app.LevelEditFieldLabel_8 = uilabel(app.Panel);
            app.LevelEditFieldLabel_8.Tooltip = {'Number of potential cells evaluated: increases recall, but greatly increases processing time'};
            app.LevelEditFieldLabel_8.Text = 'Cell Size';
            app.SizeMin = uieditfield(app.Panel, 'numeric');
            app.SizeMin.Limits = [0 Inf];
            app.SizeMin.ValueDisplayFormat = '%11.0f';
            app.SizeMin.ValueChangedFcn = createCallbackFcn(app, @SizeMinValueChanged, true);
            app.SizeMin.Tooltip = {'Min Size'};
            app.SizeMin.Value = 15;
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '________________________________________________________________________________________________________________________';
            app.TrackingOption = uiswitch(app.Panel, 'slider');
            app.TrackingOption.ValueChangedFcn = createCallbackFcn(app, @TrackingOptionValueChanged2, true);
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '____________________________________________________________________________________________________';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '____________________________________________________________________________________________________';
            app.Label_8 = uilabel(app.Panel);
            app.Label_8.Text = '%';
            app.Label_9 = uilabel(app.Panel);
            app.Label_9.Text = '%';
            app.Label_10 = uilabel(app.Panel);
            app.Label_10.Text = '%';
            app.Label_11 = uilabel(app.Panel);
            app.Label_11.Visible = 'off';
            app.Label_11.Text = '%';
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Visible = 'off';
            app.Label_13.Text = '%';
            app.ObjectSize = uislider(app.Panel, 'range');
            app.ObjectSize.Limits = [0 250];
            app.ObjectSize.MajorTicks = [0 250];
            app.ObjectSize.ValueChangedFcn = createCallbackFcn(app, @ObjectSizeValueChanged, true);
            app.ObjectSize.Value = [0 250];
            app.SizeMax = uieditfield(app.Panel, 'numeric');
            app.SizeMax.Limits = [0 Inf];
            app.SizeMax.ValueDisplayFormat = '%11.0f';
            app.SizeMax.ValueChangedFcn = createCallbackFcn(app, @SizeMaxValueChanged, true);
            app.SizeMax.Tooltip = {'Max Size'};
            app.SizeMax.Value = 250;
            app.Label_14 = uilabel(app.Panel);
            app.Label_14.Text = '________________________________________________________________________________________________________________________';
            app.Label_15 = uilabel(app.Panel);
            app.Label_15.Text = '________________________________________________________________________________________________________________________';
            app.Label_16 = uilabel(app.Panel);
            app.Label_16.Text = '________________________________________________________________________________________________________________________';
            app.Label_17 = uilabel(app.Panel);
            app.Label_17.Text = '________________________________________________________________________________________________________________________';
            app.Label_18 = uilabel(app.Panel);
            app.Label_18.Text = '________________________________________________________________________________________________________________________';
            app.Label_19 = uilabel(app.Panel);
            app.Label_19.Text = '%';
            app.FitImages = uiswitch(app.Panel, 'slider');
            app.FitImages.Tooltip = {''};
            app.FitImagestoSizeLabel = uilabel(app.Panel);
            app.FitImagestoSizeLabel.Tooltip = {'Fit image to be exact same size as processing window'; ''; 'By default, images will be padded and tiled if the size is too large'};
            app.FitImagestoSizeLabel.Text = {'Fit Images'; 'to Size'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Multires_ML_Microscopy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end